classdef reto_volcan < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        VelocidadinicialEditField       matlab.ui.control.NumericEditField
        VelocidadinicialEditFieldLabel  matlab.ui.control.Label
        AnguloEditField                 matlab.ui.control.NumericEditField
        AnguloEditFieldLabel            matlab.ui.control.Label
        DiametroEditField               matlab.ui.control.NumericEditField
        DiametroEditFieldLabel          matlab.ui.control.Label
        MasaEditField                   matlab.ui.control.NumericEditField
        MasaEditFieldLabel              matlab.ui.control.Label
        GraficarButton                  matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GraficarButton
        function GraficarButtonPushed(app, event)
            g = 9.81; % gravitational acceleration (m/s^2)
dt = 0.1; % time step for Euler's method

% Plotting the volcano as an inverted cone
[coneX, coneY, coneZ] = cylinder([0, 5]); % Reduce the radius to 5
coneZ(2, :) = -10; % Adjust to invert the height of the cone to half its original height
surf(app.UIAxes, coneX, coneY, coneZ, 'FaceAlpha', 0.5, 'EdgeColor', 'none', 'FaceColor', [0.58, 0.29, 0.00]); % Color café

% Extract values from the app's EditFields
m = app.MasaEditField.Value;
v0 = app.VelocidadinicialEditField.Value;
angle_deg = app.AnguloEditField.Value;
diameter = app.DiametroEditField.Value;
angle_rad = deg2rad(angle_deg);

% Considering the diameter for air resistance
area = pi * (diameter / 2)^2;
k = 0.05 * area; % air resistance constant adjusted for object's diameter

% Initialize values
vx = v0 * cos(angle_rad);
vy = v0 * sin(angle_rad);

x_euler = [];
y_euler = [];
z_euler = [];
x_current = 0;
z_current = 1; % Initialized to 1 to ensure the loop starts

while (angle_deg >= 0 && z_current >= 0) || (angle_deg < 0 && z_current <= 10) % Ensure it works for negative angles
    % Code for trajectory with air resistance using linear drag
    x_euler = [x_euler, x_current];
    y_euler = [y_euler, 0];
    z_euler = [z_euler, z_current];

    ax = -k/m * vx;
    az = -g - k/m * vy;

    vx = vx + ax*dt;
    vy = vy + az*dt;

    x_current = x_current + vx*dt;
    z_current = z_current + vy*dt;
end

% Code for trajectory without air resistance (outside the loop since it remains unchanged)
t_flight = (2 * v0 * sin(angle_rad)) / g;
t = linspace(0, t_flight, numel(x_euler));
x_no_drag = v0 * cos(angle_rad) * t;
z_no_drag = v0 * sin(angle_rad) * t - 0.5 * g * t.^2;

hold(app.UIAxes, 'on'); % Keep previous plots

% Plotting point by point with a pause
for i = 1:length(x_euler)
    plot3(app.UIAxes, x_euler(i), y_euler(i), z_euler(i), 'ro-'); % Red dot for current point with air resistance
    pause(0.05); % Pause for a short duration before plotting the next point. Adjust the pause value as needed.
end

for i = 1:length(x_no_drag)
    plot3(app.UIAxes, x_no_drag(i), 0, z_no_drag(i), 'bo-'); % Blue dot for current point without air resistance
    pause(0.05); % Pause for a short duration before plotting the next point. Adjust the pause value as needed.
end

% Adjustments for UIAxes
title(app.UIAxes, 'Projectile Trajectories with and without Air Resistance');
xlabel(app.UIAxes, 'Distance (m)');
ylabel(app.UIAxes, 'Y Axis');
zlabel(app.UIAxes, 'Height (m)');
view(app.UIAxes, 3); % Ensure 3D view
grid(app.UIAxes, 'on'); % Display grid lines
box(app.UIAxes, 'on'); % Display the box around the axes


% Create a matrix with the required data
data_matrix = [x_euler', z_euler']; % assuming y-velocity is zero for this 2D problem

% Create a cell array with the column names
column_names = {'X Displacement (m)', 'Z Displacement (m)'};

% Create the table and set its properties
table = uitable('Parent', app.UIFigure, 'Data', data_matrix, 'ColumnName', column_names, 'Position', [730 100 260 300]);

% Adjust the width of columns, if necessary
table.ColumnWidth = {100, 100, 100, 100};

        end

        % Callback function
        function VelocidadinicialEditFieldValueChanged(app, event)


        end

        % Value changed function: AnguloEditField
        function AnguloEditFieldValueChanged(app, event)
            value = app.AnguloEditField.Value;
            
        end

        % Value changed function: VelocidadinicialEditField
        function VelocidadinicialEditFieldValueChanged2(app, event)
            value = app.VelocidadinicialEditField.Value;
            
        end

        % Value changed function: MasaEditField
        function MasaEditFieldValueChanged(app, event)
            value = app.MasaEditField.Value;
            
        end

        % Value changed function: DiametroEditField
        function DiametroEditFieldValueChanged(app, event)
            value = app.DiametroEditField.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Simulacion del Volcan')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.GraficarButton = uibutton(app.UIFigure, 'push');
            app.GraficarButton.ButtonPushedFcn = createCallbackFcn(app, @GraficarButtonPushed, true);
            app.GraficarButton.Text = 'Graficar';
            app.MasaEditFieldLabel = uilabel(app.UIFigure);
            app.MasaEditFieldLabel.Text = 'Masa';
            app.MasaEditField = uieditfield(app.UIFigure, 'numeric');
            app.MasaEditField.ValueChangedFcn = createCallbackFcn(app, @MasaEditFieldValueChanged, true);
            app.DiametroEditFieldLabel = uilabel(app.UIFigure);
            app.DiametroEditFieldLabel.Text = 'Diametro';
            app.DiametroEditField = uieditfield(app.UIFigure, 'numeric');
            app.DiametroEditField.ValueChangedFcn = createCallbackFcn(app, @DiametroEditFieldValueChanged, true);
            app.AnguloEditFieldLabel = uilabel(app.UIFigure);
            app.AnguloEditFieldLabel.Text = 'Angulo';
            app.AnguloEditField = uieditfield(app.UIFigure, 'numeric');
            app.AnguloEditField.ValueChangedFcn = createCallbackFcn(app, @AnguloEditFieldValueChanged, true);
            app.VelocidadinicialEditFieldLabel = uilabel(app.UIFigure);
            app.VelocidadinicialEditFieldLabel.Text = 'Velocidad inicial';
            app.VelocidadinicialEditField = uieditfield(app.UIFigure, 'numeric');
            app.VelocidadinicialEditField.ValueChangedFcn = createCallbackFcn(app, @VelocidadinicialEditFieldValueChanged2, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = reto_volcan

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end